classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Spinner       matlab.ui.control.Spinner
        SpinnerLabel  matlab.ui.control.Label
        Button        matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Paraméterek
N = 100; % A térkép felbontása
x = linspace(-1, 1, N); % x koordináták
y = linspace(-1, 1, N); % y koordináták
[X, Y] = meshgrid(x, y); % Koordináta háló

% Töltés paraméterei
q = 1; % Töltés nagysága
q_loc = [0 0]; % Töltés helye

% Elektromos térerősség számítása
R = sqrt((X-q_loc(1)).^2 + (Y-q_loc(2)).^2); % Távolság a töltéstől
Ex = q*(X-q_loc(1))./(R.^3); % x irányú térerősség
Ey = q*(Y-q_loc(2))./(R.^3); % y irányú térerősség

% Erővonalak rajzolása
quiver(X, Y, Ex, Ey);
title('Erővonalak');
xlabel('x');
ylabel('y');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end