classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DeleteChargeButton    matlab.ui.control.Button
        IntensityEditField    matlab.ui.control.EditField
        ErssgEditFieldLabel   matlab.ui.control.Label
        YEditField            matlab.ui.control.EditField
        YpozciEditFieldLabel  matlab.ui.control.Label
        XEditField            matlab.ui.control.EditField
        XpozciEditFieldLabel  matlab.ui.control.Label
        NewChargeButton       matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        number_of_charges;
        charges = {};
    end
    
    methods (Access = private)
        function showCharges(app)
            cla(app.UIAxes);
            if app.number_of_charges < 1
                return
            end
            min_x = app.charges{1}.posx;
            min_y = app.charges{1}.posy;
            max_x = app.charges{1}.posx;
            max_y = app.charges{1}.posy;
            max_intensity = abs(app.charges{1}.intensity);
            
            for c = 1 : length(app.charges)
                Q = app.charges{c};
                % Get min max values for meshgrid size
                if Q.posx < min_x
                    min_x = Q.posx;
                elseif Q.posx > max_x
                    max_x = Q.posx;
                end
                if Q.posy < min_y
                    min_y = Q.posy;
                elseif Q.posy > max_y
                    max_y = Q.posy;
                end
                if abs(Q.intensity) > max_intensity
                    max_intensity = abs(Q.intensity);
                end
            end
          
            [X, Y] = meshgrid(min_x-10*max_intensity:max_intensity:max_x+10*max_intensity, min_y-10*max_intensity:max_intensity:max_y+10*max_intensity);
            
                
            % Create a matrix for the X and Y components 
            % of the vectors representing the electrical force
            % affecting each probe charge
            Ex = zeros(size(X));
            Ey = zeros(size(Y));

            % Calculate and sum the X and Y components of
            % the force affecting each probe charge
            for c = 1 : length(app.charges)
                Q = app.charges{c};

                % The distance between the current charge and
                % the probe charge at given coordinate
                r = sqrt( (X - Q.posx).^2 + (Y - Q.posy).^2 );

                % Coulomb's law, we can omit Boltzmann's
                % constant as this is for a representational
                % purpose and we want to see the vectors
                Ex = Ex + Q.intensity./r.^2 .* (X - Q.posx);
                Ey = Ey + Q.intensity./r.^2 .* (Y - Q.posy);
                
                % Call the predefined call method for the
                % current charge
                Q.draw(app);

                
            end
            
            hold(app.UIAxes, 'on');

            % Draw the above calculated vectors
            quiver(app.UIAxes, X, Y, Ex, Ey, "Color", "#1100C5");
            
            hold(app.UIAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.number_of_charges = 0;
        end

        % Button pushed function: NewChargeButton
        function newCharge(app, event)
            % Read x, y and intensity values
            x = str2double(app.XEditField.Value);
            y = str2double(app.YEditField.Value);
            intensity = str2double(app.IntensityEditField.Value);
                
            % If any of the paramaters isn't a number, do nothing
            if isnan(x) || isnan(y) || isnan(intensity) 
                return;
            end

            % Add the new row to the table
            app.number_of_charges = app.number_of_charges + 1;
            app.UITable.Data = [app.UITable.Data; app.number_of_charges, x, y, intensity];

            % Reset EditFields
            app.XEditField.Value = '';
            app.YEditField.Value = '';
            app.IntensityEditField.Value = '';
        
            % Add Charge object to the array
            app.charges{end+1} = Charge(x, y, intensity);

            showCharges(app);
        end

        % Cell selection callback: UITable
        function callSelectionCallback(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                    app.UITable.UserData = indices(1);
            end
        end

        % Button pushed function: DeleteChargeButton
        function deleteCharge(app, event)
            % Get the current data
            data = app.UITable.Data;
        
            % Get the currently selected cell
            sel = app.UITable.UserData;
        
            % If no cell is selected, do nothing
            if isempty(sel)
                    return;
            end
        
            % Get the row to delete
            rowToDelete = sel(1);
            
            % Delete the row from the data
            data(rowToDelete, :) = [];
        
            % Update the table data
            app.UITable.Data = data;

            % Delete object from the array
            app.charges(rowToDelete) = [];
            app.number_of_charges = app.number_of_charges-1;
            
            % Correction of ids
            counter = 1;
            updated_ids=app.UITable.Data;
            for i = 1:size(updated_ids, 1)
                    updated_ids(i, 1) = counter;
                    counter = counter + 1;
            end
            app.UITable.Data = updated_ids;

            showCharges(app);
        end

        % Cell edit callback: UITable
        function cellEditCallback(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Update the value in the array
            if indices(2)==2
                    app.charges{indices(1)}.posx = newData;
            elseif indices(2)==3
                    app.charges{indices(1)}.posy = newData;
            elseif indices(2)==4
                    app.charges{indices(1)}.intensity = newData;
            end

            showCharges(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Sorszám'; 'X pozíció'; 'Y pozíció'; 'Erősség'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @cellEditCallback, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @callSelectionCallback, true);
            app.UITable.Multiselect = 'off';
            app.NewChargeButton = uibutton(app.UIFigure, 'push');
            app.NewChargeButton.ButtonPushedFcn = createCallbackFcn(app, @newCharge, true);
            app.NewChargeButton.Text = 'Új töltés felvétele';
            app.XpozciEditFieldLabel = uilabel(app.UIFigure);
            app.XpozciEditFieldLabel.Text = 'X pozíció:';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YpozciEditFieldLabel = uilabel(app.UIFigure);
            app.YpozciEditFieldLabel.Text = 'Y pozíció:';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ErssgEditFieldLabel = uilabel(app.UIFigure);
            app.ErssgEditFieldLabel.Text = 'Erősség:';
            app.IntensityEditField = uieditfield(app.UIFigure, 'text');
            app.DeleteChargeButton = uibutton(app.UIFigure, 'push');
            app.DeleteChargeButton.ButtonPushedFcn = createCallbackFcn(app, @deleteCharge, true);
            app.DeleteChargeButton.Text = 'Kijelölt töltés törlése';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end